classdef PVpredictGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        LabelEditField          matlab.ui.control.Label
        Country                 matlab.ui.control.EditField
        Label2                  matlab.ui.control.Label
        City                    matlab.ui.control.EditField
        LabelNumericEditField   matlab.ui.control.Label
        Longitude               matlab.ui.control.NumericEditField
        Label3                  matlab.ui.control.Label
        Latitude                matlab.ui.control.NumericEditField
        Label4                  matlab.ui.control.Label
        Altitude                matlab.ui.control.NumericEditField
        Panel2                  matlab.ui.container.Panel
        Label7                  matlab.ui.control.Label
        ppeak                   matlab.ui.control.NumericEditField
        Label8                  matlab.ui.control.Label
        tilt                    matlab.ui.control.NumericEditField
        Label9                  matlab.ui.control.Label
        azimuth                 matlab.ui.control.NumericEditField
        Label10                 matlab.ui.control.Label
        eff                     matlab.ui.control.NumericEditField
        Label11                 matlab.ui.control.Label
        NoModules               matlab.ui.control.NumericEditField
        Label12                 matlab.ui.control.Label
        Amodule                 matlab.ui.control.NumericEditField
        Panel3                  matlab.ui.container.Panel
        Label13                 matlab.ui.control.Label
        AcqWeatherDir           matlab.ui.control.EditField
        Label14                 matlab.ui.control.Label
        Once                    matlab.ui.control.Button
        SpecificDuration        matlab.ui.control.Button
        Continuous              matlab.ui.control.Button
        LabelNumericEditField2  matlab.ui.control.Label
        SpecDays                matlab.ui.control.NumericEditField
        LabelLamp               matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        Stop                    matlab.ui.control.Button
        WeatherServerLabel      matlab.ui.control.Label
        AcqServer               matlab.ui.control.EditField
        Panel4                  matlab.ui.container.Panel
        LabelDropDown           matlab.ui.control.Label
        DropDownTrain           matlab.ui.control.DropDown
        TSTy                    matlab.ui.control.NumericEditField
        TSTm                    matlab.ui.control.NumericEditField
        TSTd                    matlab.ui.control.NumericEditField
        TSThr                   matlab.ui.control.NumericEditField
        TSTmin                  matlab.ui.control.NumericEditField
        TSTsec                  matlab.ui.control.NumericEditField
        TETsec                  matlab.ui.control.NumericEditField
        TETmin                  matlab.ui.control.NumericEditField
        TEThr                   matlab.ui.control.NumericEditField
        TETd                    matlab.ui.control.NumericEditField
        TETm                    matlab.ui.control.NumericEditField
        TETy                    matlab.ui.control.NumericEditField
        Label15                 matlab.ui.control.Label
        Label16                 matlab.ui.control.Label
        Label17                 matlab.ui.control.Label
        Label18                 matlab.ui.control.Label
        Label19                 matlab.ui.control.Label
        Label20                 matlab.ui.control.Label
        Label21                 matlab.ui.control.Label
        Label22                 matlab.ui.control.Label
        CheckTrain              matlab.ui.control.Button
        Panel5                  matlab.ui.container.Panel
        LabelTextArea           matlab.ui.control.Label
        ACserver                matlab.ui.control.TextArea
        Panel6                  matlab.ui.container.Panel
        Label23                 matlab.ui.control.Label
        DropDownPredict         matlab.ui.control.DropDown
        PSTy                    matlab.ui.control.NumericEditField
        PSTm                    matlab.ui.control.NumericEditField
        PSTd                    matlab.ui.control.NumericEditField
        PSThr                   matlab.ui.control.NumericEditField
        PSTmin                  matlab.ui.control.NumericEditField
        PSTsec                  matlab.ui.control.NumericEditField
        PETsec                  matlab.ui.control.NumericEditField
        PETmin                  matlab.ui.control.NumericEditField
        PEThr                   matlab.ui.control.NumericEditField
        PETd                    matlab.ui.control.NumericEditField
        PETm                    matlab.ui.control.NumericEditField
        PETy                    matlab.ui.control.NumericEditField
        Label24                 matlab.ui.control.Label
        Label25                 matlab.ui.control.Label
        Label26                 matlab.ui.control.Label
        Label27                 matlab.ui.control.Label
        Label28                 matlab.ui.control.Label
        Label29                 matlab.ui.control.Label
        Label30                 matlab.ui.control.Label
        Label31                 matlab.ui.control.Label
        CheckPredict            matlab.ui.control.Button
        Label33                 matlab.ui.control.Label
        PredictDir              matlab.ui.control.EditField
        Label32                 matlab.ui.control.Label
        t1m                     matlab.ui.control.NumericEditField
        Generate                matlab.ui.control.Button
        Help                    matlab.ui.control.Button
        About                   matlab.ui.control.Button
        GenLamp                 matlab.ui.control.Lamp
        SaveIn                  matlab.ui.control.Button
        ImportIn                matlab.ui.control.Button
        AdvSett                 matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        AcquireTimer=timer; % Define Timer object for acquiring weather
        
        %for advanced settings
        Temp=25;
        TempEff=0.88;
        Zen=58.0362;
        ZenEff=0.56;
        ZenDir='increasing';
        OhmEff=0.98;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.Resize = 'off';
            
        end

        % Button pushed function: About
        function AboutButtonPushed(app, event)
            AboutF = uifigure('Name','About','Position',[100 100 656 293])
            
            label1 = uilabel(AboutF,...
                'FontSize',14,'FontWeight','bold',...
                'FontColor', [0 0.0627 0.7686],'Position',[20 255 287 18],...
                'Text','Created based on the code developed by:');
            
            label2 = uilabel(AboutF,...
                'FontSize',14,'FontWeight','bold',...
                'Position',[20 235 615 18],...
                'Text','Institute of Energy Economy and Application Technology, Technical University of Munich');
            
            label3 = uilabel(AboutF,...
                'FontSize',14,'FontWeight','bold',...
                'FontColor', [0 0.0627 0.7686],'Position',[20 195 164 18],...
                'Text','Reference Publications:');
            
            label4 = uilabel(AboutF,...
                'FontSize',14,'FontWeight','bold',...
                'Position',[20 82 580 51],...
                'Text',{'W. El-Baz, P. Tzscheutschler, and U. Wagner, “Day-ahead probabilistic PV generation'; '          forecast for buildings energy management systems,” Solar Energy, vol. 171,'; '          pp. 478–490, Sep. 2018.'});
            
            label5 = uilabel(AboutF,...
                'FontSize',14,'FontWeight','bold',...
                'Position',[20 142 601 51],...
                'Text',{'W. El-Baz, M. Seufzger, S. Lutzenberger, P. Tzscheutschler, and U. Wagner, “Impact of'; '          probabilistic small-scale photovoltaic generation forecast on energy management'; '          systems,” Solar Energy, vol. 165, pp. 136–146, May 2018.'});
            
            label6 = uilabel(AboutF,...
                'FontSize',14,'FontWeight','bold',...
                'FontColor', [0 0.0627 0.7686],'Position',[20 45 246 18],...
                'Text','Graphical User Interface created by:');
            
            label7 = uilabel(AboutF,...
                'FontSize',14,'FontWeight','bold',...
                'Position',[20 25 600 18],...
                'Text','Mohamed Eldakadosi (m.eldakadosi@tum.de); August 2018');
        end

        % Button pushed function: CheckPredict
        function CheckPredictButtonPushed(app, event)
            %Prediction start and end times
            StartTimeP = datetime(app.PSTy.Value, app.PSTm.Value, app.PSTd.Value, ...
                app.PSThr.Value, app.PSTmin.Value, app.PSTsec.Value);
            EndTimeP = datetime(app.PETy.Value, app.PETm.Value, app.PETd.Value, ...
                app.PEThr.Value, app.PETmin.Value, app.PETsec.Value);
            %Training start and end times
            StartTimeT = datetime(app.TSTy.Value, app.TSTm.Value, app.TSTd.Value, ...
                app.TSThr.Value, app.TSTmin.Value, app.TSTsec.Value);
            EndTimeT = datetime(app.TETy.Value, app.TETm.Value, app.TETd.Value, ...
                app.TEThr.Value, app.TETmin.Value, app.TETsec.Value);
            
            %check the weather forecast data whether it meets requirements
            x = checkWeatherData(app.AcqWeatherDir.Value);
            if x==0
                return
                
            else     %if weather forecast data meets requirements, then check the users' inputs
                z = checkPredictTime(app.AcqWeatherDir.Value,app.PredictDir.Value,StartTimeP,EndTimeP,StartTimeT,EndTimeT,1);
                
            end
        end

        % Button pushed function: CheckTrain
        function CheckTrainButtonPushed(app, event)
            %Training start and end times
            StartTimeT = datetime(app.TSTy.Value, app.TSTm.Value, app.TSTd.Value, ...
                app.TSThr.Value, app.TSTmin.Value, app.TSTsec.Value);
            EndTimeT = datetime(app.TETy.Value, app.TETm.Value, app.TETd.Value, ...
                app.TEThr.Value, app.TETmin.Value, app.TETsec.Value);
            
            %check the weather forecast data whether it meets requirements
            x = checkWeatherData(app.AcqWeatherDir.Value);
            if x==0
                return
                
            else     %if weather forecast data meets requirements, then check the users' inputs
                y = checkTrainingTime(app.AcqWeatherDir.Value,StartTimeT,EndTimeT,1);
                
            end
        end

        % Button pushed function: Continuous
        function ContinuousButtonPushed(app, event)
            if (strcmp(app.AcqWeatherDir.Value(end),'\')==0)   %check if the directory has \ at the end
                app.AcqWeatherDir.Value(end+1)='\';            %if not, add it!
            end
            
            if (exist(app.AcqWeatherDir.Value,'dir')==0)    %check if the directory exists
                opts.WindowStyle='modal';
                opts.Interpreter='tex';
                errordlg('\fontsize{14}Directory does not exist!','Weather Forecast Error',opts)
            else      %if Dir exists, then acquire weather
                %adjust parameters of the timer object
                app.AcquireTimer.ExecutionMode = 'fixedSpacing';  %timer will run every fixed period of time
                app.AcquireTimer.Period = 86400;  %timer will run every 1 day (86400 seconds)
                app.AcquireTimer.TimerFcn= @(~,~) AcquireWeather(app.Country.Value, app.City.Value, app.AcqServer.Value, app.AcqWeatherDir.Value);  %function which will be executed repeatedly
                app.AcquireTimer.StopFcn= @(~,~) set(app.Lamp,'Color',[1 0 0]);  %turn lamp color to red to indicate 'stop' status when timer finishes
                app.Lamp.Color= 'green';  %turn lamp color to green to indicate 'running' status
                
                start(app.AcquireTimer)     %start the timer and execute AcquireWeather
            end
        end

        % Value changed function: DropDownPredict
        function DropDownPredictValueChanged(app, event)
            valueP = app.DropDownPredict.Value;
            %Training start and end times
            StartTimeT = datetime(app.TSTy.Value, app.TSTm.Value, app.TSTd.Value, ...
                app.TSThr.Value, app.TSTmin.Value, app.TSTsec.Value);
            EndTimeT = datetime(app.TETy.Value, app.TETm.Value, app.TETd.Value, ...
                app.TEThr.Value, app.TETmin.Value, app.TETsec.Value);
            
            
            if strcmp(valueP,'Auto')
                %check the weather forecast data whether it meets requirements
                x = checkWeatherData(app.AcqWeatherDir.Value);
                if x==0
                    app.DropDownPredict.Value='Manual';
                    return
                else
                    [app.PSTd.Value, app.PSTm.Value, app.PSTy.Value, app.PSThr.Value, ...
                        app.PSTmin.Value, app.PSTsec.Value, app.PETd.Value, app.PETm.Value, ...
                        app.PETy.Value, app.PEThr.Value, app.PETmin.Value , app.PETsec.Value] ...
                        = autoFillPredict(app.AcqWeatherDir.Value);
                end
                
                %make Training times uneditable (depending on the weather forecast data available)
                app.PSTy.Editable = 'off'; app.PSTm.Editable = 'off';
                app.PSTd.Editable = 'off'; app.PSThr.Editable = 'off';
                app.PSTmin.Editable = 'off'; app.PSTsec.Editable = 'off';
                app.PETy.Editable = 'off'; app.PETm.Editable = 'off';
                app.PETd.Editable = 'off'; app.PEThr.Editable = 'off';
                app.PETmin.Editable = 'off'; app.PETsec.Editable = 'off';
                app.CheckPredict.Enable = 'off';
            else
                %make Training times editable
                app.PSTy.Editable = 'on'; app.PSTm.Editable = 'on';
                app.PSTd.Editable = 'on'; app.PSThr.Editable = 'on';
                app.PSTmin.Editable = 'on'; app.PSTsec.Editable = 'on';
                app.PETy.Editable = 'on'; app.PETm.Editable = 'on';
                app.PETd.Editable = 'on'; app.PEThr.Editable = 'on';
                app.PETmin.Editable = 'on'; app.PETsec.Editable = 'on';
                app.CheckPredict.Enable = 'on';
            end
        end

        % Value changed function: DropDownTrain
        function DropDownTrainValueChanged(app, event)
            value = app.DropDownTrain.Value;
            %Training start and end times
            StartTimeT = datetime(app.TSTy.Value, app.TSTm.Value, app.TSTd.Value, ...
                app.TSThr.Value, app.TSTmin.Value, app.TSTsec.Value);
            EndTimeT = datetime(app.TETy.Value, app.TETm.Value, app.TETd.Value, ...
                app.TEThr.Value, app.TETmin.Value, app.TETsec.Value);
            
            if strcmp(value,'Auto')
                %check the weather forecast data whether it meets requirements
                x = checkWeatherData(app.AcqWeatherDir.Value);
                if x==0
                    app.DropDownTrain.Value='Manual';
                    return
                else
                    [app.TSTd.Value, app.TSTm.Value, app.TSTy.Value, app.TSThr.Value, ...
                        app.TSTmin.Value, app.TSTsec.Value, app.TETd.Value, app.TETm.Value, ...
                        app.TETy.Value, app.TEThr.Value, app.TETmin.Value , app.TETsec.Value] ...
                        = autoFillTrain(app.AcqWeatherDir.Value);
                end
                
                %make Training times uneditable (depending on the weather forecast data available)
                app.TSTy.Editable = 'off'; app.TSTm.Editable = 'off';
                app.TSTd.Editable = 'off'; app.TSThr.Editable = 'off';
                app.TSTmin.Editable = 'off'; app.TSTsec.Editable = 'off';
                app.TETy.Editable = 'off'; app.TETm.Editable = 'off';
                app.TETd.Editable = 'off'; app.TEThr.Editable = 'off';
                app.TETmin.Editable = 'off'; app.TETsec.Editable = 'off';
                app.CheckTrain.Enable = 'off';
            else
                %make Training times editable
                app.TSTy.Editable = 'on'; app.TSTm.Editable = 'on';
                app.TSTd.Editable = 'on'; app.TSThr.Editable = 'on';
                app.TSTmin.Editable = 'on'; app.TSTsec.Editable = 'on';
                app.TETy.Editable = 'on'; app.TETm.Editable = 'on';
                app.TETd.Editable = 'on'; app.TEThr.Editable = 'on';
                app.TETmin.Editable = 'on'; app.TETsec.Editable = 'on';
                app.CheckTrain.Enable = 'on';
            end
        end

        % Button pushed function: Generate
        function GenerateButtonPushed(app, event)
            %             try
            
            %change lamp color to green to indicate generation has started
            set(app.GenLamp,'Color',[0 1 0])
            
            %location's parameters
            Location.latitude=app.Latitude.Value;
            Location.longitude=app.Longitude.Value;
            Location.altitude=app.Altitude.Value;
            
            %PV system's parameters
            PVsystem.powerpeak=app.ppeak.Value;
            PVsystem.tilt=app.tilt.Value;
            PVsystem.azimuth=app.azimuth.Value;
            PVsystem.eff=app.eff.Value;
            PVsystem.No_modules=app.NoModules.Value;
            PVsystem.Amodule=app.Amodule.Value;
            PVsystem.OArea=app.NoModules.Value*app.Amodule.Value;
            
            %Training start and end times
            StartTimeT = datetime(app.TSTy.Value, app.TSTm.Value, app.TSTd.Value, ...
                app.TSThr.Value, app.TSTmin.Value, app.TSTsec.Value);
            EndTimeT = datetime(app.TETy.Value, app.TETm.Value, app.TETd.Value, ...
                app.TEThr.Value, app.TETmin.Value, app.TETsec.Value);
            
            %Prediction start and end times
            StartTimeP = datetime(app.PSTy.Value, app.PSTm.Value, app.PSTd.Value, ...
                app.PSThr.Value, app.PSTmin.Value, app.PSTsec.Value);
            EndTimeP = datetime(app.PETy.Value, app.PETm.Value, app.PETd.Value, ...
                app.PEThr.Value, app.PETmin.Value, app.PETsec.Value);
            
            timestep1m = app.t1m.Value;
            
            if (strcmp(app.AcqWeatherDir.Value(end),'\')==0)   %check if the Weather Forecast directory has \ at the end
                app.AcqWeatherDir.Value(end+1)='\';            %if not, add it!
            end
            
            if (strcmp(app.PredictDir.Value(end),'\')==0)   %check if the prediction result directory has \ at the end
                app.PredictDir.Value(end+1)='\';            %if not, add it!
            end
            
            %first check the weather data (directory,sufficient data,etc.)
            %if weather data, training and prediction parameters meet requirements, then proceed
            x = checkWeatherData(app.AcqWeatherDir.Value);
            if x==0
                return
            else
                %check validity of user's input regarding training
                y = checkTrainingTime(app.AcqWeatherDir.Value,StartTimeT,EndTimeT,0);
                if y==0
                    return
                else
                    %check validity of user's input regarding prediction
                    z = checkPredictTime(app.AcqWeatherDir.Value,app.PredictDir.Value,StartTimeP,EndTimeP,StartTimeT,EndTimeT,0);
                    if z==0
                        return
                    end
                end
            end
            
            
            %             if (exist(app.AcqWeatherDir.Value,'dir')==0)    %check if the Weather Forecast directory exists
            %                 opts.WindowStyle='modal';
            %                 opts.Interpreter='tex';
            %                 errordlg('\fontsize{14} Directory for weather forecast does not exist!','Weather Forecast Error',opts)
            %                 return
            %             end
            %
            %             if (exist(app.PredictDir.Value,'dir')==0)    %check if the Prediction Results directory exists
            %                 opts.WindowStyle='modal';
            %                 opts.Interpreter='tex';
            %                 errordlg('\fontsize{14} Directory for saving prediction results does not exist!','Results Error',opts)
            %                 return
            %             end
            
            
            MainForApp2(StartTimeT,EndTimeT,timestep1m,StartTimeP,EndTimeP,Location,...
                PVsystem,app.AcqWeatherDir.Value,app.PredictDir.Value,app.ACserver.Value{1},...
                app.Temp,app.TempEff,app.Zen,app.ZenEff,app.ZenDir,app.OhmEff)
            
            set(app.GenLamp,'Color',[1 0 0])
            
            %             catch
            %                 set(app.GenLamp,'Color',[1 0 0])
            %                 opts.WindowStyle='non-modal';
            %                 opts.Interpreter='tex';
            %                 errordlg('\fontsize{14}Error! Please check your Internet connection or input data.','Error',opts)
            %             end
        end

        % Button pushed function: Once
        function OnceButtonPushed(app, event)
            if (strcmp(app.AcqWeatherDir.Value(end),'\')==0)   %check if the directory has \ at the end
                app.AcqWeatherDir.Value(end+1)='\';            %if not, add it!
            end
            
            if (exist(app.AcqWeatherDir.Value,'dir')==0)    %check if the directory exists
                opts.WindowStyle='modal';
                opts.Interpreter='tex';
                errordlg('\fontsize{14} Directory does not exist!','Weather Forecast Error',opts)
            else      %if Dir exists, then acquire weather
                AcquireWeather(app.Country.Value, app.City.Value, app.AcqServer.Value, app.AcqWeatherDir.Value);  %function which will be executed once
            end
        end

        % Button pushed function: SpecificDuration
        function SpecificDurationButtonPushed(app, event)
            if (strcmp(app.AcqWeatherDir.Value(end),'\')==0)   %check if the directory has \ at the end
                app.AcqWeatherDir.Value(end+1)='\';            %if not, add it!
            end
            
            if (exist(app.AcqWeatherDir.Value,'dir')==0)    %check if the directory exists
                opts.WindowStyle='modal';
                opts.Interpreter='tex';
                errordlg('\fontsize{14} Directory does not exist!','Weather Forecast Error',opts)
                
            else      %if Dir exists, then acquire weather
                %adjust parameters of the timer object
                app.AcquireTimer.ExecutionMode = 'fixedSpacing';  %timer will run every fixed period of time
                app.AcquireTimer.Period = 86400;  %timer will run every 1 day (86400 seconds)
                app.AcquireTimer.Taskstoexecute = app.SpecDays.Value;  %timer will run for a certain number of times (as per User selection)
                app.AcquireTimer.TimerFcn= @(~,~) AcquireWeather(app.Country.Value, app.City.Value, app.AcqServer.Value, app.AcqWeatherDir.Value);  %function which will be executed repeatedly
                app.AcquireTimer.StopFcn= @(~,~) set(app.Lamp,'Color',[1 0 0]);  %turn lamp color to red to indicate 'stop' status when timer finishes
                app.Lamp.Color= 'green';  %turn lamp color to green to indicate 'running' status
                
                start(app.AcquireTimer)     %start the timer and execute AcquireWeather
                if exist('app.AcquireTimer')
                    delete(app.AcquireTimer)
                end
            end
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            stop(app.AcquireTimer)   %stop the timer upon User request (STOP is pushed)
            app.Lamp.Color='red';   %turn lamp color to red to indicate 'stop' status
            if exist('app.AcquireTimer')
                delete(app.AcquireTimer)
            end
        end

        % Button pushed function: SaveIn
        function SaveInButtonPushed(app, event)
            %This function stores the input parameters for the PV system so the user can import them later
            
            
            if (exist(app.PredictDir.Value,'dir')==0)
                opts.WindowStyle='non-modal';
                opts.Interpreter='tex';
                errordlg({'\fontsize{14}Given directory does not exist. '},'Save Input Error',opts)
                return
            end
            
            if (strcmp(app.PredictDir.Value(end),'\')==0)   %check if the prediction result directory has \ at the end
                app.PredictDir.Value(end+1)='\';            %if not, add it!
            end
            
            PVi.Long=app.Longitude.Value;      PVi.Lat=app.Latitude.Value;
            PVi.Alt=app.Altitude.Value;        PVi.City=app.City.Value;
            PVi.Country=app.Country.Value;
            
            PVi.PP=app.ppeak.Value;
            PVi.Tilt=app.tilt.Value;
            PVi.Azi=app.azimuth.Value;
            PVi.Eff=app.eff.Value;
            PVi.NoMod=app.NoModules.Value;
            PVi.AMod=app.Amodule.Value;
            
            PVi.WDir=app.AcqWeatherDir.Value;
            PVi.AC=app.ACserver.Value{1};
            
            PVInName=[app.PredictDir.Value 'Input_Data.mat'];
            save(PVInName,'PVi')
            
            opts.WindowStyle='replace';
            opts.Interpreter='tex';
            msgbox('\fontsize{13}Input data has been stored successfully.','Save Input',opts)
            
        end

        % Button pushed function: ImportIn
        function ImportInButtonPushed(app, event)
            %This function imports previously stored input data by the user
            
            opts.WindowStyle='non-modal';
            opts.Interpreter='tex';
            
            if (exist(app.PredictDir.Value,'dir')==0)
                errordlg({'\fontsize{14}Given directory does not exist. '},'Import Input Error',opts)
                return
            end
            
            if (strcmp(app.PredictDir.Value(end),'\')==0)   %check if the prediction result directory has \ at the end
                app.PredictDir.Value(end+1)='\';            %if not, add it!
            end
            
            PVInName=[app.PredictDir.Value 'Input_Data.mat'];
            
            if (exist(PVInName)==0)
                errordlg({'\fontsize{13}The input data file does not exist.'},'Import Input Error',opts)
                return
            end
            
            if (exist(PVInName)==2)
                load(PVInName,'PVi')
                app.Longitude.Value=PVi.Long;      app.Latitude.Value=PVi.Lat;
                app.Altitude.Value=PVi.Alt;        app.City.Value=PVi.City;
                app.Country.Value=PVi.Country;
                
                app.ppeak.Value=PVi.PP;
                app.tilt.Value=PVi.Tilt;
                app.azimuth.Value=PVi.Azi;
                app.eff.Value=PVi.Eff;
                app.NoModules.Value=PVi.NoMod;
                app.Amodule.Value=PVi.AMod;
                
                app.AcqWeatherDir.Value=PVi.WDir;
                app.ACserver.Value{1}=PVi.AC;
                msgbox('\fontsize{13}Input data has been imported successfully.','Import Input',opts)
                
            end
        end

        % Button pushed function: AdvSett
        function AdvSettButtonPushed(app, event)
            
            if (strcmp(app.PredictDir.Value(end),'\')==0)   %check if the prediction result directory has \ at the end
                app.PredictDir.Value(end+1)='\';            %if not, add it!
            end
            
            AdvSet(app.Temp,app.TempEff,app.Zen,app.ZenEff,app.ZenDir,app.OhmEff,app.PredictDir.Value);
            
            FileName=[app.PredictDir.Value 'Advanced.mat'];
            
            if exist(FileName)==2
                load(FileName,'AdvN','ZDir')
                app.Temp=AdvN(1);
                app.TempEff=1-AdvN(2)/100;
                app.Zen=AdvN(3);
                app.ZenEff=1-AdvN(4)/100;
                app.OhmEff=1-AdvN(5)/100;
                app.ZenDir=ZDir;
                delete(FileName)
            end
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'The P3 System';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'The P    System';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Location Settings';
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Country';
            app.Country = uieditfield(app.Panel, 'text');
            app.Country.Value = 'Germany';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'City';
            app.City = uieditfield(app.Panel, 'text');
            app.City.Value = 'Munich';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Longitude [deg]';
            app.Longitude = uieditfield(app.Panel, 'numeric');
            app.Longitude.Limits = [-180 180];
            app.Longitude.ValueDisplayFormat = '%11.10g';
            app.Longitude.Value = 11.5681850999999;
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Latitude [deg]';
            app.Latitude = uieditfield(app.Panel, 'numeric');
            app.Latitude.Limits = [-90 90];
            app.Latitude.ValueDisplayFormat = '%11.10g';
            app.Latitude.Value = 48.1505119;
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Altitude [m]';
            app.Altitude = uieditfield(app.Panel, 'numeric');
            app.Altitude.ValueDisplayFormat = '%11.10g';
            app.Altitude.Value = 515.898;
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'PV System Settings';
            app.Label7 = uilabel(app.Panel2);
            app.Label7.Text = 'Peak Power [kW]';
            app.ppeak = uieditfield(app.Panel2, 'numeric');
            app.ppeak.Limits = [0 Inf];
            app.ppeak.Value = 3;
            app.Label8 = uilabel(app.Panel2);
            app.Label8.Text = 'Tilt [deg]';
            app.tilt = uieditfield(app.Panel2, 'numeric');
            app.tilt.Limits = [0 90];
            app.tilt.Value = 30;
            app.Label9 = uilabel(app.Panel2);
            app.Label9.Text = 'Azimuth [deg]';
            app.azimuth = uieditfield(app.Panel2, 'numeric');
            app.azimuth.Limits = [0 360];
            app.azimuth.Value = 200;
            app.Label10 = uilabel(app.Panel2);
            app.Label10.Text = 'Efficiency';
            app.eff = uieditfield(app.Panel2, 'numeric');
            app.eff.Limits = [0 1];
            app.eff.Value = 0.16;
            app.Label11 = uilabel(app.Panel2);
            app.Label11.Text = 'Number of Modules';
            app.NoModules = uieditfield(app.Panel2, 'numeric');
            app.NoModules.Limits = [0 Inf];
            app.NoModules.Value = 12;
            app.Label12 = uilabel(app.Panel2);
            app.Label12.Text = 'Area per Module [m2]';
            app.Amodule = uieditfield(app.Panel2, 'numeric');
            app.Amodule.Limits = [0 Inf];
            app.Amodule.Value = 1.67;
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Acquire 10-Day Weather Forecast';
            app.Label13 = uilabel(app.Panel3);
            app.Label13.Text = 'Save Results to';
            app.AcqWeatherDir = uieditfield(app.Panel3, 'text');
            app.Label14 = uilabel(app.Panel3);
            app.Label14.Text = 'Acquire Forecast...';
            app.Once = uibutton(app.Panel3, 'push');
            app.Once.ButtonPushedFcn = createCallbackFcn(app, @OnceButtonPushed, true);
            app.Once.Text = 'Once';
            app.SpecificDuration = uibutton(app.Panel3, 'push');
            app.SpecificDuration.ButtonPushedFcn = createCallbackFcn(app, @SpecificDurationButtonPushed, true);
            app.SpecificDuration.Text = 'Specific Duration';
            app.Continuous = uibutton(app.Panel3, 'push');
            app.Continuous.ButtonPushedFcn = createCallbackFcn(app, @ContinuousButtonPushed, true);
            app.Continuous.Text = 'Continuous';
            app.LabelNumericEditField2 = uilabel(app.Panel3);
            app.LabelNumericEditField2.Text = 'days';
            app.SpecDays = uieditfield(app.Panel3, 'numeric');
            app.SpecDays.Limits = [0 Inf];
            app.SpecDays.Value = 10;
            app.LabelLamp = uilabel(app.Panel3);
            app.LabelLamp.Text = {'Acquiring'; 'Status'};
            app.Lamp = uilamp(app.Panel3);
            app.Stop = uibutton(app.Panel3, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = 'STOP';
            app.WeatherServerLabel = uilabel(app.Panel3);
            app.WeatherServerLabel.Text = 'Weather Server';
            app.AcqServer = uieditfield(app.Panel3, 'text');
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'Training Settings';
            app.LabelDropDown = uilabel(app.Panel4);
            app.LabelDropDown.Text = 'Period Selection';
            app.DropDownTrain = uidropdown(app.Panel4);
            app.DropDownTrain.Items = {'Auto', 'Manual'};
            app.DropDownTrain.ValueChangedFcn = createCallbackFcn(app, @DropDownTrainValueChanged, true);
            app.DropDownTrain.Value = 'Manual';
            app.TSTy = uieditfield(app.Panel4, 'numeric');
            app.TSTy.Limits = [2000 3000];
            app.TSTy.Value = 2000;
            app.TSTm = uieditfield(app.Panel4, 'numeric');
            app.TSTm.Limits = [1 12];
            app.TSTm.ValueDisplayFormat = '%11.2g';
            app.TSTm.Value = 1;
            app.TSTd = uieditfield(app.Panel4, 'numeric');
            app.TSTd.Limits = [1 31];
            app.TSTd.ValueDisplayFormat = '%11.2g';
            app.TSTd.Value = 1;
            app.TSThr = uieditfield(app.Panel4, 'numeric');
            app.TSThr.Limits = [0 23];
            app.TSTmin = uieditfield(app.Panel4, 'numeric');
            app.TSTmin.Limits = [0 59];
            app.TSTsec = uieditfield(app.Panel4, 'numeric');
            app.TSTsec.Limits = [0 59];
            app.TETsec = uieditfield(app.Panel4, 'numeric');
            app.TETsec.Limits = [0 59];
            app.TETmin = uieditfield(app.Panel4, 'numeric');
            app.TETmin.Limits = [0 59];
            app.TEThr = uieditfield(app.Panel4, 'numeric');
            app.TEThr.Limits = [0 23];
            app.TETd = uieditfield(app.Panel4, 'numeric');
            app.TETd.Limits = [1 31];
            app.TETd.ValueDisplayFormat = '%11.2g';
            app.TETd.Value = 1;
            app.TETm = uieditfield(app.Panel4, 'numeric');
            app.TETm.Limits = [1 12];
            app.TETm.ValueDisplayFormat = '%11.2g';
            app.TETm.Value = 1;
            app.TETy = uieditfield(app.Panel4, 'numeric');
            app.TETy.Limits = [2000 3000];
            app.TETy.Value = 2000;
            app.Label15 = uilabel(app.Panel4);
            app.Label15.Text = 'Start Time';
            app.Label16 = uilabel(app.Panel4);
            app.Label16.Text = 'End Time';
            app.Label17 = uilabel(app.Panel4);
            app.Label17.Text = 'dd';
            app.Label18 = uilabel(app.Panel4);
            app.Label18.Text = 'mm';
            app.Label19 = uilabel(app.Panel4);
            app.Label19.Text = 'yyyy';
            app.Label20 = uilabel(app.Panel4);
            app.Label20.Text = 'HH';
            app.Label21 = uilabel(app.Panel4);
            app.Label21.Text = 'MM';
            app.Label22 = uilabel(app.Panel4);
            app.Label22.Text = 'SS';
            app.CheckTrain = uibutton(app.Panel4, 'push');
            app.CheckTrain.ButtonPushedFcn = createCallbackFcn(app, @CheckTrainButtonPushed, true);
            app.CheckTrain.Text = 'Check Validity';
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Title = 'PV System Output Settings';
            app.LabelTextArea = uilabel(app.Panel5);
            app.LabelTextArea.Text = 'Obtain AC Power from';
            app.ACserver = uitextarea(app.Panel5);
            app.Panel6 = uipanel(app.UIFigure);
            app.Panel6.Title = 'Prediction Settings';
            app.Label23 = uilabel(app.Panel6);
            app.Label23.Text = 'Period Selection';
            app.DropDownPredict = uidropdown(app.Panel6);
            app.DropDownPredict.Items = {'Auto', 'Manual'};
            app.DropDownPredict.ValueChangedFcn = createCallbackFcn(app, @DropDownPredictValueChanged, true);
            app.DropDownPredict.Value = 'Manual';
            app.PSTy = uieditfield(app.Panel6, 'numeric');
            app.PSTy.Limits = [2000 3000];
            app.PSTy.Value = 2000;
            app.PSTm = uieditfield(app.Panel6, 'numeric');
            app.PSTm.Limits = [1 12];
            app.PSTm.Value = 1;
            app.PSTd = uieditfield(app.Panel6, 'numeric');
            app.PSTd.Limits = [1 31];
            app.PSTd.Value = 1;
            app.PSThr = uieditfield(app.Panel6, 'numeric');
            app.PSThr.Limits = [0 23];
            app.PSTmin = uieditfield(app.Panel6, 'numeric');
            app.PSTmin.Limits = [0 59];
            app.PSTsec = uieditfield(app.Panel6, 'numeric');
            app.PSTsec.Limits = [0 59];
            app.PETsec = uieditfield(app.Panel6, 'numeric');
            app.PETsec.Limits = [0 59];
            app.PETmin = uieditfield(app.Panel6, 'numeric');
            app.PETmin.Limits = [0 59];
            app.PEThr = uieditfield(app.Panel6, 'numeric');
            app.PEThr.Limits = [0 23];
            app.PETd = uieditfield(app.Panel6, 'numeric');
            app.PETd.Limits = [1 31];
            app.PETd.Value = 1;
            app.PETm = uieditfield(app.Panel6, 'numeric');
            app.PETm.Limits = [1 12];
            app.PETm.Value = 1;
            app.PETy = uieditfield(app.Panel6, 'numeric');
            app.PETy.Limits = [2000 3000];
            app.PETy.Value = 2000;
            app.Label24 = uilabel(app.Panel6);
            app.Label24.Text = 'Start Time';
            app.Label25 = uilabel(app.Panel6);
            app.Label25.Text = 'End Time';
            app.Label26 = uilabel(app.Panel6);
            app.Label26.Text = 'dd';
            app.Label27 = uilabel(app.Panel6);
            app.Label27.Text = 'mm';
            app.Label28 = uilabel(app.Panel6);
            app.Label28.Text = 'yyyy';
            app.Label29 = uilabel(app.Panel6);
            app.Label29.Text = 'HH';
            app.Label30 = uilabel(app.Panel6);
            app.Label30.Text = 'MM';
            app.Label31 = uilabel(app.Panel6);
            app.Label31.Text = 'SS';
            app.CheckPredict = uibutton(app.Panel6, 'push');
            app.CheckPredict.ButtonPushedFcn = createCallbackFcn(app, @CheckPredictButtonPushed, true);
            app.CheckPredict.Text = 'Check Validity';
            app.Label33 = uilabel(app.Panel6);
            app.Label33.Text = 'Save Results to';
            app.PredictDir = uieditfield(app.Panel6, 'text');
            app.Label32 = uilabel(app.Panel6);
            app.Label32.Text = 'Enhanced Resolution [min]';
            app.t1m = uieditfield(app.Panel6, 'numeric');
            app.t1m.Limits = [1 59];
            app.t1m.Value = 10;
            app.Generate = uibutton(app.UIFigure, 'push');
            app.Generate.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.Generate.Text = 'Generate PV Forecast';
            app.Help = uibutton(app.UIFigure, 'push');
            app.Help.Text = 'Help';
            app.About = uibutton(app.UIFigure, 'push');
            app.About.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.About.Text = 'About';
            app.GenLamp = uilamp(app.UIFigure);
            app.SaveIn = uibutton(app.UIFigure, 'push');
            app.SaveIn.ButtonPushedFcn = createCallbackFcn(app, @SaveInButtonPushed, true);
            app.SaveIn.Text = 'Save Input';
            app.ImportIn = uibutton(app.UIFigure, 'push');
            app.ImportIn.ButtonPushedFcn = createCallbackFcn(app, @ImportInButtonPushed, true);
            app.ImportIn.Text = 'Import Input';
            app.AdvSett = uibutton(app.UIFigure, 'push');
            app.AdvSett.ButtonPushedFcn = createCallbackFcn(app, @AdvSettButtonPushed, true);
            app.AdvSett.Text = 'Advanced Settings';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PVpredictGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end